classdef PID_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        JustwaitforawhileDLabel   matlab.ui.control.Label
        KIresponsesreallyslowduetothealgorithmLabel  matlab.ui.control.Label
        GenerateButton            matlab.ui.control.Button
        ResetButton               matlab.ui.control.Button
        Spinner_6                 matlab.ui.control.Spinner
        Spinner_5                 matlab.ui.control.Spinner
        Spinner_4                 matlab.ui.control.Spinner
        InputSignalButtonGroup    matlab.ui.container.ButtonGroup
        ImpulseButton             matlab.ui.control.RadioButton
        StepButton                matlab.ui.control.RadioButton
        KPSlider                  matlab.ui.control.Slider
        KPSliderLabel             matlab.ui.control.Label
        KDSlider                  matlab.ui.control.Slider
        KDSliderLabel             matlab.ui.control.Label
        KISlider                  matlab.ui.control.Slider
        KISliderLabel             matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        Demo10CreatedbyJhinLabel  matlab.ui.control.Label
        TabGroup                  matlab.ui.container.TabGroup
        Tab                       matlab.ui.container.Tab
        Lamp                      matlab.ui.control.Lamp
        s2Label                   matlab.ui.control.Label
        s1Label                   matlab.ui.control.Label
        s0Label                   matlab.ui.control.Label
        S2Spinner                 matlab.ui.control.Spinner
        S1Spinner                 matlab.ui.control.Spinner
        S0Spinner                 matlab.ui.control.Spinner
        Tab2                      matlab.ui.container.Tab
        Inputsomerealnumbersthatarenotgreaterthan0Label  matlab.ui.control.Label
        EditField_6               matlab.ui.control.NumericEditField
        EditField_5               matlab.ui.control.NumericEditField
        EditField_4               matlab.ui.control.NumericEditField
        EditField_3               matlab.ui.control.NumericEditField
        EditField_2               matlab.ui.control.NumericEditField
        EditField                 matlab.ui.control.NumericEditField
        ZerosLabel                matlab.ui.control.Label
        PolesLabel                matlab.ui.control.Label
        Lamp_2                    matlab.ui.control.Lamp
        YlabelSpinner             matlab.ui.control.Spinner
        XlabelSpinner             matlab.ui.control.Spinner
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: KPSlider
        function KPSliderValueChanging(app, event)
            changingValue = event.Value;
            KP=changingValue;
            KI=app.KISlider.Value;
            KD=app.KDSlider.Value;
            in=app.StepButton.Value;
            if app.Lamp.Color==[0 1 0]
            A=app.S0Spinner.Value;
            B=app.S1Spinner.Value;
            C=app.S2Spinner.Value;
            OUTPUT=PIDtest(KP,KI,KD,A,B,C,in);
            else
                p=[];
                P1=app.EditField.Value;
                P2=app.EditField_2.Value;
                P3=app.EditField_3.Value;
                P4=app.EditField_4.Value;
                Z1=app.EditField_5.Value;
                Z2=app.EditField_6.Value;
                if P1<0||P1==0
                    p=[p P1];
                end
                if P2<0||P2==0
                    p=[p P2];
                end
                if P3<0||P3==0
                    p=[p P3];
                end
                if P4<0||P4==0
                    p=[p P4];
                end               
                z=[];
                if real(Z1)<0||real(Z1)==0
                    z=[z Z1];
                end
                if real(Z2)<0||real(Z2)==0
                    z=[z Z2];
                end
                OUTPUT=PIDtest2(KP,KI,KD,p,z,in);
            end
            fplot(app.UIAxes,OUTPUT,[0 50])
            grid(app.UIAxes,"on")  
        end

        % Value changed function: KISlider
        function KISliderValueChanged(app, event)
            value = app.KISlider.Value;
            KP=app.KPSlider.Value;
            KI=value;
            KD=app.KDSlider.Value;
            in=app.StepButton.Value;
            if app.Lamp.Color==[0 1 0]
            A=app.S0Spinner.Value;
            B=app.S1Spinner.Value;
            C=app.S2Spinner.Value;
            OUTPUT=PIDtest(KP,KI,KD,A,B,C,in);
            else
                p=[];
                P1=app.EditField.Value;
                P2=app.EditField_2.Value;
                P3=app.EditField_3.Value;
                P4=app.EditField_4.Value;
                Z1=app.EditField_5.Value;
                Z2=app.EditField_6.Value;
                if P1<0||P1==0
                    p=[p P1];
                end
                if P2<0||P2==0
                    p=[p P2];
                end
                if P3<0||P3==0
                    p=[p P3];
                end
                if P4<0||P4==0
                    p=[p P4];
                end               
                z=[];
                if real(Z1)<0||real(Z1)==0
                    z=[z Z1];
                end
                if real(Z2)<0||real(Z2)==0
                    z=[z Z2];
                end
                OUTPUT=PIDtest2(KP,KI,KD,p,z,in);
            end            
            fplot(app.UIAxes,OUTPUT,[0 50])
            grid(app.UIAxes,"on")
        end

        % Value changing function: KDSlider
        function KDSliderValueChanging(app, event)
            changingValue = event.Value;
            KP=app.KPSlider.Value;
            KI=app.KISlider.Value;
            KD=changingValue;
            in=app.StepButton.Value;
            if app.Lamp.Color==[0 1 0]
            A=app.S0Spinner.Value;
            B=app.S1Spinner.Value;
            C=app.S2Spinner.Value;
            OUTPUT=PIDtest(KP,KI,KD,A,B,C,in);
            else
                p=[];
                P1=app.EditField.Value;
                P2=app.EditField_2.Value;
                P3=app.EditField_3.Value;
                P4=app.EditField_4.Value;
                Z1=app.EditField_5.Value;
                Z2=app.EditField_6.Value;
                if P1<0||P1==0
                    p=[p P1];
                end
                if P2<0||P2==0
                    p=[p P2];
                end
                if P3<0||P3==0
                    p=[p P3];
                end
                if P4<0||P4==0
                    p=[p P4];
                end               
                z=[];
                if real(Z1)<0||real(Z1)==0
                    z=[z Z1];
                end
                if real(Z2)<0||real(Z2)==0
                    z=[z Z2];
                end
                OUTPUT=PIDtest2(KP,KI,KD,p,z,in);
            end            
            fplot(app.UIAxes,OUTPUT,[0 50])   
            grid(app.UIAxes,"on")


        end

        % Callback function
        function SpinnerValueChanging(app, event)
%             changingValue = event.Value;
            app.Lamp.Color=[0.65 0.65 0.65];
            app.Lamp_2.Color=[0 1 0];
        end

        % Callback function
        function Spinner_2ValueChanging(app, event)
%             changingValue = event.Value;
            app.Lamp.Color=[0.65 0.65 0.65];
            app.Lamp_2.Color=[0 1 0];
        end

        % Callback function
        function Spinner_3ValueChanging(app, event)
%             changingValue = event.Value;
            app.Lamp.Color=[0.65 0.65 0.65];
            app.Lamp_2.Color=[0 1 0];            
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
                KP=app.KPSlider.Value;
                KI=app.KISlider.Value;
                KD=app.KDSlider.Value;
                in=app.StepButton.Value;

            if app.Lamp.Color==[0 1 0]
                A=app.S0Spinner.Value;
                B=app.S1Spinner.Value;
                C=app.S2Spinner.Value;
                OUTPUT=PIDtest(KP,KI,KD,A,B,C,in); 
            else
                p=[];
                P1=app.EditField.Value;
                P2=app.EditField_2.Value;
                P3=app.EditField_3.Value;
                P4=app.EditField_4.Value;
                Z1=app.EditField_5.Value;
                Z2=app.EditField_6.Value;
                if P1<0||P1==0
                    p=[p P1];
                end
                if P2<0||P2==0
                    p=[p P2];
                end
                if P3<0||P3==0
                    p=[p P3];
                end
                if P4<0||P4==0
                    p=[p P4];
                end               
                z=[];
                if real(Z1)<0||real(Z1)==0
                    z=[z Z1];
                end
                if real(Z2)<0||real(Z2)==0
                    z=[z Z2];
                end
                OUTPUT=PIDtest2(KP,KI,KD,p,z,in);
            end
            fplot(app.UIAxes,OUTPUT,[0 50])
        end

        % Value changing function: Spinner_4
        function Spinner_4ValueChanging(app, event)
            changingValue = event.Value;
            app.KPSlider.Limits=[1,changingValue];
        end

        % Value changing function: Spinner_5
        function Spinner_5ValueChanging(app, event)
            changingValue = event.Value;
            app.KISlider.Limits=[0,changingValue];
        end

        % Value changing function: Spinner_6
        function Spinner_6ValueChanging(app, event)
            changingValue = event.Value;
            app.KDSlider.Limits=[0,changingValue];
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
          app.KPSlider.Limits=[1,15];
          app.KISlider.Limits=[0,3];
          app.KDSlider.Limits=[0,20];
          app.KPSlider.Value=1;
          app.KISlider.Value=0;
          app.KDSlider.Value=0;
          app.StepButton.Value=1;
          app.ImpulseButton.Value=0;
          app.Spinner_4.Value=15;
          app.Spinner_5.Value=3;
          app.Spinner_6.Value=20;
          app.XlabelSpinner.Value=20;
          app.YlabelSpinner.Value=20;
          app.UIAxes.XLim=[0 20];
          app.UIAxes.YLim=[-10 20];
          app.EditField.Value=1;
          app.EditField_2.Value=1;
          app.EditField_3.Value=1;
          app.EditField_4.Value=1;
          app.EditField_5.Value=1;
          app.EditField_6.Value=1;
          app.S0Spinner.Value=1;
          app.S1Spinner.Value=1;
          app.S2Spinner.Value=1;
          GenerateButtonPushed(app, event)
        end

        % Value changing function: XlabelSpinner
        function XlabelSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.UIAxes.XLim=[0,changingValue];
        end

        % Value changing function: YlabelSpinner
        function YlabelSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.UIAxes.YLim=[-10,changingValue];
        end

        % Value changing function: S0Spinner
        function S0SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.Lamp_2.Color=[0.65 0.65 0.65];
            app.Lamp.Color=[0 1 0];
        end

        % Value changing function: S1Spinner
        function S1SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.Lamp_2.Color=[0.65 0.65 0.65];
            app.Lamp.Color=[0 1 0];
        end

        % Value changing function: S2Spinner
        function S2SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.Lamp_2.Color=[0.65 0.65 0.65];
            app.Lamp.Color=[0 1 0];
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.Lamp.Color=[0.65 0.65 0.65];
            app.Lamp_2.Color=[0 1 0];
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.Lamp.Color=[0.65 0.65 0.65];
            app.Lamp_2.Color=[0 1 0];            
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            app.Lamp.Color=[0.65 0.65 0.65];
            app.Lamp_2.Color=[0 1 0];
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            app.Lamp.Color=[0.65 0.65 0.65];
            app.Lamp_2.Color=[0 1 0];            
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
             value = app.EditField_5.Value;
            app.Lamp.Color=[0.65 0.65 0.65];
            app.Lamp_2.Color=[0 1 0];            
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
             value = app.EditField_6.Value;
            app.Lamp.Color=[0.65 0.65 0.65];
            app.Lamp_2.Color=[0 1 0];
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {482, 482};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {289, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.KISliderLabel = uilabel(app.LeftPanel);
            app.KISliderLabel.Text = 'KI';
            app.KISlider = uislider(app.LeftPanel);
            app.KISlider.Limits = [0 3];
            app.KISlider.ValueChangedFcn = createCallbackFcn(app, @KISliderValueChanged, true);
            app.KDSliderLabel = uilabel(app.LeftPanel);
            app.KDSliderLabel.Text = 'KD';
            app.KDSlider = uislider(app.LeftPanel);
            app.KDSlider.Limits = [0 20];
            app.KDSlider.ValueChangingFcn = createCallbackFcn(app, @KDSliderValueChanging, true);
            app.KPSliderLabel = uilabel(app.LeftPanel);
            app.KPSliderLabel.Text = 'KP';
            app.KPSlider = uislider(app.LeftPanel);
            app.KPSlider.Limits = [1 15];
            app.KPSlider.ValueChangingFcn = createCallbackFcn(app, @KPSliderValueChanging, true);
            app.KPSlider.Value = 1;
            app.InputSignalButtonGroup = uibuttongroup(app.LeftPanel);
            app.InputSignalButtonGroup.Title = 'Input Signal';
            app.StepButton = uiradiobutton(app.InputSignalButtonGroup);
            app.StepButton.Text = 'Step';
            app.StepButton.Value = true;
            app.ImpulseButton = uiradiobutton(app.InputSignalButtonGroup);
            app.ImpulseButton.Text = 'Impulse';
            app.Spinner_4 = uispinner(app.LeftPanel);
            app.Spinner_4.ValueChangingFcn = createCallbackFcn(app, @Spinner_4ValueChanging, true);
            app.Spinner_4.Limits = [10 100];
            app.Spinner_4.Value = 15;
            app.Spinner_5 = uispinner(app.LeftPanel);
            app.Spinner_5.Step = 0.5;
            app.Spinner_5.ValueChangingFcn = createCallbackFcn(app, @Spinner_5ValueChanging, true);
            app.Spinner_5.Limits = [1 10];
            app.Spinner_5.Value = 3;
            app.Spinner_6 = uispinner(app.LeftPanel);
            app.Spinner_6.ValueChangingFcn = createCallbackFcn(app, @Spinner_6ValueChanging, true);
            app.Spinner_6.Limits = [10 50];
            app.Spinner_6.Value = 20;
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.GenerateButton = uibutton(app.LeftPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.KIresponsesreallyslowduetothealgorithmLabel = uilabel(app.LeftPanel);
            app.KIresponsesreallyslowduetothealgorithmLabel.Text = 'KI responses really slow due to the algorithm';
            app.JustwaitforawhileDLabel = uilabel(app.LeftPanel);
            app.JustwaitforawhileDLabel.Text = ' Just wait for a while  :D';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Response')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 20];
            app.UIAxes.YLim = [-10 20];
            app.UIAxes.ZLim = [0 1];
            app.XlabelSpinner = uispinner(app.RightPanel);
            app.XlabelSpinner.Step = 10;
            app.XlabelSpinner.ValueChangingFcn = createCallbackFcn(app, @XlabelSpinnerValueChanging, true);
            app.XlabelSpinner.Limits = [10 50];
            app.XlabelSpinner.Value = 20;
            app.YlabelSpinner = uispinner(app.RightPanel);
            app.YlabelSpinner.Step = 10;
            app.YlabelSpinner.ValueChangingFcn = createCallbackFcn(app, @YlabelSpinnerValueChanging, true);
            app.YlabelSpinner.Limits = [10 50];
            app.YlabelSpinner.Value = 20;
            app.TabGroup = uitabgroup(app.RightPanel);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.S0Spinner = uispinner(app.Tab);
            app.S0Spinner.Step = 0.1;
            app.S0Spinner.ValueChangingFcn = createCallbackFcn(app, @S0SpinnerValueChanging, true);
            app.S0Spinner.Limits = [-10 10];
            app.S0Spinner.Value = 1;
            app.S1Spinner = uispinner(app.Tab);
            app.S1Spinner.Step = 0.1;
            app.S1Spinner.ValueChangingFcn = createCallbackFcn(app, @S1SpinnerValueChanging, true);
            app.S1Spinner.Limits = [-10 10];
            app.S1Spinner.Value = 1;
            app.S2Spinner = uispinner(app.Tab);
            app.S2Spinner.Step = 0.1;
            app.S2Spinner.ValueChangingFcn = createCallbackFcn(app, @S2SpinnerValueChanging, true);
            app.S2Spinner.Limits = [-10 10];
            app.S2Spinner.Value = 1;
            app.s0Label = uilabel(app.Tab);
            app.s0Label.Text = 's^0';
            app.s1Label = uilabel(app.Tab);
            app.s1Label.Text = 's^1';
            app.s2Label = uilabel(app.Tab);
            app.s2Label.Text = 's^2';
            app.Lamp = uilamp(app.Tab);
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Lamp_2 = uilamp(app.Tab2);
            app.PolesLabel = uilabel(app.Tab2);
            app.PolesLabel.Text = 'Poles:';
            app.ZerosLabel = uilabel(app.Tab2);
            app.ZerosLabel.Text = 'Zeros:';
            app.EditField = uieditfield(app.Tab2, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 1;
            app.EditField_2 = uieditfield(app.Tab2, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 1;
            app.EditField_3 = uieditfield(app.Tab2, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Value = 1;
            app.EditField_4 = uieditfield(app.Tab2, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_4.Value = 1;
            app.EditField_5 = uieditfield(app.Tab2, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_5.Value = 1;
            app.EditField_6 = uieditfield(app.Tab2, 'numeric');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.EditField_6.Value = 1;
            app.Inputsomerealnumbersthatarenotgreaterthan0Label = uilabel(app.Tab2);
            app.Inputsomerealnumbersthatarenotgreaterthan0Label.Text = 'Input some real numbers that are not greater than 0';
            app.Demo10CreatedbyJhinLabel = uilabel(app.RightPanel);
            app.Demo10CreatedbyJhinLabel.Text = 'Demo1.0  Created by Jhin';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PID_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end